classdef CTScanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PhantomGenerationTab            matlab.ui.container.Tab
        StructureColorEditField         matlab.ui.control.NumericEditField
        StructureColorEditFieldLabel    matlab.ui.control.Label
        StructureSizeEditField          matlab.ui.control.NumericEditField
        StructureSizeEditFieldLabel     matlab.ui.control.Label
        ShowphatomButton                matlab.ui.control.Button
        MatrixSizeEditField             matlab.ui.control.NumericEditField
        PhantomStructureButtonGroup     matlab.ui.container.ButtonGroup
        HeadPhantomButton               matlab.ui.control.RadioButton
        RectangularButton               matlab.ui.control.RadioButton
        CircularButton                  matlab.ui.control.RadioButton
        MatrixSizeEditFieldLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ScannerControlsTab              matlab.ui.container.Tab
        RotationStepAngleSlider         matlab.ui.control.Slider
        RotationStepAngleSliderLabel    matlab.ui.control.Label
        RunButton                       matlab.ui.control.StateButton
        SourceDistanceDropDown          matlab.ui.control.DropDown
        DetectorArrayTypeDropDownLabel  matlab.ui.control.Label
        DetectorArrayTypeDropDown       matlab.ui.control.DropDown
        SourceDistanceDropDownLabel     matlab.ui.control.Label
        DetectorDensityDropDown         matlab.ui.control.DropDown
        DetectorDensityDropDownLabel    matlab.ui.control.Label
        NumberofDetectorDropDown        matlab.ui.control.DropDown
        NumberofDetectorDropDownLabel   matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        ImageAnalysisTab                matlab.ui.container.Tab
        TypeofImageAnalysisButtonGroup  matlab.ui.container.ButtonGroup
        SIProfilesButton                matlab.ui.control.RadioButton
        ImageDifferenceButton           matlab.ui.control.RadioButton
        SIandContrastButton             matlab.ui.control.RadioButton
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MatrixSize
        selectedButton 
        generatedPhantom
        NumberofDetector
        arrTypeDropDown
        selectedDensity
        sourceDest
        rotationSlider
    end
    
    methods (Access = private)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%% GENERATR PHANTOM %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function generatedphantom = generate_phantom(app)
            matrixsize = app.MatrixSizeEditField.Value;
            sizefactor = app.StructureSizeEditField.Value;
            colorfactor = app.StructureColorEditField.Value;

            % Clear previous phantom
            app.generatedPhantom = zeros(matrixsize);

            % Generate the phantom based on the selected structure and matrix size
            switch app.selectedButton
                case 'Circular'
                    % Create a blank image
                    app.generatedPhantom = zeros(matrixsize);

                    % Define the properties of the circle
                    centerX = matrixsize / 2;
                    centerY = matrixsize / 2;
                    radius = matrixsize / 2.2;
        
                    % Draw outer circle (the body)
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if (x - centerX)^2 + (y - centerY)^2 <= radius^2
                                app.generatedPhantom(x, y) = 0.4;
                            end
                        end
                    end

                    % Draw inside circle
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if (x - matrixsize*0.5)^2 + (y - matrixsize*0.5)^2 <= (matrixsize*0.1*sizefactor)^2
                                app.generatedPhantom(x, y) = colorfactor;
                            end
                        end
                    end

                    % Draw left inside circle
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if (x - matrixsize*0.55)^2 + (y - matrixsize*0.2)^2 <= (matrixsize*0.05*sizefactor)^2
                                app.generatedPhantom(x, y) = colorfactor;
                            end
                        end
                    end

                    % Draw the bottom circle
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if (x - matrixsize*0.75)^2 + (y - matrixsize*0.5)^2 <= (matrixsize*0.08*sizefactor)^2
                                app.generatedPhantom(x, y) = colorfactor;
                            end
                        end
                    end

                    % Draw the top baby circle
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if (x - matrixsize*0.25)^2 + (y - matrixsize*0.45)^2 <= (matrixsize*0.03*sizefactor)^2
                                app.generatedPhantom(x, y) = colorfactor;
                            end
                        end
                    end


                case 'Rectangular'
                    % Create a blank image
                    app.generatedPhantom = zeros(matrixsize);

                    centerX = matrixsize / 2;
                    centerY = matrixsize / 2;
                    radius = matrixsize / 2.2;

                    % Draw outer circle (the body)
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if (x - centerX)^2 + (y - centerY)^2 <= radius^2
                                app.generatedPhantom(x, y) = 0.4;
                            end
                        end
                    end

                    width = matrixsize*0.2*sizefactor;
                    height = matrixsize*0.4*sizefactor;

                    % Define the properties of the rectangle
                    left = (matrixsize/2-width/2);
                    right = (matrixsize/2+width/2);
                    top = (matrixsize/2-height/2);
                    bottom = (matrixsize/2+height/2);
        
                    % Draw the rectangle in the image
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if x >= left && x <= right && y >= top && y <= bottom
                                app.generatedPhantom(x, y) = colorfactor;
                            end
                        end
                    end

                case 'Head Phantom'
                    % Use MATLAB's built-in phantom function to generate a head phantom
                    app.generatedPhantom = phantom(matrixsize);
            end
        
            % Display the phantom in the UIAxes
            imshow(app.generatedPhantom, 'Parent', app.UIAxes);

            % Assign the value to the output variable
            generatedphantom = app.generatedPhantom;
        end 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%% OPERATE SCANNER %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function scanner_data = operate_scanner(app, phantom, num_detectors, detector_density, detector_array_type, source_distance, rotation_step_angle)
            disp('Reached operate_scanner - Start');

            % Get the dimensions of the phantom image
            dims = size(phantom);
            % Print the dimensions
            disp('Dimensions of the phantom image:');
            disp(dims);
        
            % Initialize parameters
            %rows = app.MatrixSizeEditField.Value;
            cols = app.MatrixSizeEditField.Value;
            rotation_angles = 0:rotation_step_angle:360 - rotation_step_angle;
            num_angles = length(rotation_angles);
        
            % Initialize scanner_readings matrix
            scanner_readings = zeros(num_detectors, num_angles);
        
            % Determine the positions of the detectors based on the array type
            if strcmp(detector_array_type, 'Linear')
                detector_positions = linspace(1, cols, num_detectors);
            elseif strcmp(detector_array_type, 'Arc')
                % For an arc, we need to calculate the positions differently
                % This requires more complex geometry calculations
                % Here's a simple example for illustration
                detector_positions = arc_positions(app, num_detectors, cols, source_distance);
            else
                error('Unknown detector array type');
            end
                
            % Iterate over each rotation angle
            for angleIdx = 1:num_angles
                % Rotate the phantom image
                rotated_phantom = imrotate(phantom, -rotation_angles(angleIdx), 'crop', 'bilinear');
        
                % Collect data for each detector position
                for detectorIdx = 1:num_detectors
                    % Calculate the projection for each detector
                    % This part needs to be adjusted based on your detector geometry
                    projection = sum(rotated_phantom(:, round(detector_positions(detectorIdx))));
                    scanner_readings(detectorIdx, angleIdx) = projection * detector_density;
                end
            end
        
            % Store the scanner readings in the structure
            scanner_data.readings = scanner_readings;
            scanner_data.rotation_angles = rotation_angles;
        
            disp('Reached operate_scanner - End');
        end

        function positions = arc_positions(~, num_detectors, image_width, radius)
            % This function calculates the coordinates of detectors positioned on an arc.
            % The arc is centered at the middle of the image width and has the specified radius.
        
            % Center of the arc (directly above the middle of the image width)
            x_center = image_width / 2;
            y_center = 0; % Adjust this if the center is not at the image edge
        
            % Define the angular span of the arc
            theta = linspace(-pi/4, pi/4, num_detectors); % Example angle range for the arc
    
            %max_angle = atan((image_width / 2) / radius);
            %angles = linspace(-max_angle, max_angle, num_detectors);
            %F1 = fanbeam(P,D,'FanSensorSpacing',dsensor1);
            %dsensor2 = 1;
            %F2 = fanbeam(P,D,'FanSensorSpacing',dsensor2);

            % Initialize positions array
            positions = zeros(num_detectors, 2); % For storing x and y coordinates
        
            % Calculate x and y coordinates for each detector
            for i = 1:num_detectors
                positions(i, 1) = radius * cos(theta(i)) + x_center; % x-coordinate
                positions(i, 2) = radius * sin(theta(i)) + y_center; % y-coordinate
            end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%% RECONSTRUCT IMAGE %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function reconstructed_image = reconstruct_image(~, scanner_data)
            disp('Reached reconstruct_image - Start');
            
            % Extract projection data and angles for iradon
            projections = scanner_data.readings; % Assuming readings are the projections
            angles = scanner_data.rotation_angles;

            % Image reconstruction using iradon
            reconstructed_image = iradon(projections, angles);

            % Get the dimensions of the reconstructed image
            dims = size(reconstructed_image);
        
            % Print the dimensions
            disp('Dimensions of the reconstructed image:');
            disp(dims);
            disp('Reached reconstruct_image - End');
            disp('')
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%% SI and CONTRAST ANALYSIS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function calculateContrast(app)
            % Ensure the phantom is generated
           if isempty(app.generatedPhantom)
               disp('Phantom not generated yet.');
               return;
           end
           % Check if the reconstructed image is available
           reconstructed_image = app.reconstruct_image(app.operate_scanner(app.generatedPhantom, app.NumberofDetector, app.selectedDensity, app.arrTypeDropDown, app.sourceDest, app.rotationSlider));
           % Prompt the user to choose which image to analyze
           choice = uiconfirm(app.UIFigure, 'Which image would you like to analyze for SI and Contrast?', 'Select Image', ...
                              'Options', {'Original', 'Reconstructed'}, ...
                              'DefaultOption', 1, 'CancelOption', 2);
           % Determine which image to use based on user choice
           if strcmp(choice, 'Original')
               selectedImage = app.generatedPhantom;
           elseif strcmp(choice, 'Reconstructed')
               selectedImage = reconstructed_image;
           else
               return; % User cancelled or closed the dialog
           end
           % Display the selected image in a new figure for point selection
           figure, imshow(selectedImage);
           title('Select two points on the image');
           subtitle('Double click the point to confirm your selection');
           % Let the user select two points
           h1 = drawpoint('Color','r');
           pause(.5); % Wait for the user to place the first point
           point1 = round(h1.Position);
           h2 = drawpoint('Color','b');
           pause(.5); % Wait for the user to place the second point
           point2 = round(h2.Position);
           % Close the figure after selection
           close(gcf);
           % Extract the intensity values at these points
           intensity1 = selectedImage(point1(2), point1(1)); % y, x
           intensity2 = selectedImage(point2(2), point2(1)); % y, x
           % Calculate and display contrast
           contrast = abs(intensity1 - intensity2);
           disp(['Contrast between selected points: ', num2str(contrast)]);
           disp(['Point 1 (', num2str(point1(1)), ', ', num2str(point1(2)), '): Intensity = ', num2str(intensity1)]);
           disp(['Point 2 (', num2str(point2(1)), ', ', num2str(point2(2)), '): Intensity = ', num2str(intensity2)]);
           % Displaying the results in UIAxes3 or another UI component
           cla(app.UIAxes3);  % Clear existing content
           text(app.UIAxes3, 0.5, 0.5, sprintf('Contrast: %f\nPoint 1 (x: %d, y: %d) Intensity: %f\nPoint 2 (x: %d, y: %d) Intensity: %f', contrast, point1(1), point1(2), intensity1, point2(1), point2(2), intensity2), 'HorizontalAlignment', 'center');
        end
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%% IMAGE DIFFERENCE ANALYSIS %%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function imageDifferenceAnalysis(app)
           % Generate the reconstructed image
           reconstructed_image = app.reconstruct_image(app.operate_scanner(app.generatedPhantom, app.NumberofDetector, app.selectedDensity, app.arrTypeDropDown, app.sourceDest, app.rotationSlider));
           % Resize the reconstructed image to match the original phantom size
           reconstructed_image_resized = imresize(reconstructed_image, [app.MatrixSize app.MatrixSize]);
           % Create a composite image
           composite_image = zeros(app.MatrixSize, app.MatrixSize * 2); % Double the width
           composite_image(:, 1:app.MatrixSize) = app.generatedPhantom; % Place original image on the left
           composite_image(:, app.MatrixSize+1:end) = reconstructed_image_resized; % Place reconstructed image on the right
           % Display the composite image in UIAxes3
           imshow(composite_image, 'Parent', app.UIAxes3);
           title(app.UIAxes3, 'Original and Reconstructed Images Side by Side');
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%% SINGAL INTENSITY PROFILE %%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function signalIntensityProfile(app)
            % Use the reconstructed image for analysis
            reconstructed_image = app.reconstruct_image(app.operate_scanner(app.generatedPhantom, app.NumberofDetector, app.selectedDensity, app.arrTypeDropDown, app.sourceDest, app.rotationSlider));
         
            % Prompt the user to choose which phantom to analyze
            choice = uiconfirm(app.UIFigure, 'Which phantom would you like to analyze?', 'Select Phantom', ...
                               'Options', {'Original', 'Reconstructed'}, ...
                               'DefaultOption', 1, 'CancelOption', 2);
         
            % Determine which phantom to use based on user choice
            if strcmp(choice, 'Original')
                selectedPhantom = app.generatedPhantom;
            elseif strcmp(choice, 'Reconstructed')
                selectedPhantom = reconstructed_image;
            else
                return; % User cancelled or closed the dialog
            end
         
            % Show the selected phantom and let the user draw a line
            figure, imshow(selectedPhantom);
            title('Draw a line for signal intensity profile');
         
            % User draws a line
            h = drawline('Color','r');
         
            % Get line coordinates
            coords = round(h.Position);
         
            % Extract intensity values along the line
            intensityProfile = improfile(selectedPhantom, coords(:,1), coords(:,2));
         
            % Plot the intensity profile in a new figure
            figure;
            plot(intensityProfile);
            title('Signal Intensity Profile');
            xlabel('Position along the line');
            ylabel('Intensity');
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Set default values for MatrixSize and selectedButton
            app.MatrixSize = 700;  % Set your desired default matrix size
            app.selectedButton = 'Circular';  % Set your desired default phantom structure

        % Update the UI elements with the default values
            app.MatrixSizeEditField.Value = app.MatrixSize;
            app.PhantomStructureButtonGroup.SelectedObject = app.CircularButton;

        % Call the function to generate and display the default phantom
            app.ShowphatomButtonPushed();
        % Set default selection for TypeofImageAnalysisButtonGroup
            %app.TypeofImageAnalysisButtonGroup.SelectedObject = app.ImageDifferenceButton;
            %app.TypeofImageAnalysisButtonGroupSelectionChanged();

        end

        % Button pushed function: ShowphatomButton
        function ShowphatomButtonPushed(app, event)
            app.generate_phantom()
        end

        % Selection changed function: PhantomStructureButtonGroup
        function PhantomStructureButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.PhantomStructureButtonGroup.SelectedObject.Text;
        end

        % Value changed function: NumberofDetectorDropDown
        function NumberofDetectorDropDownValueChanged(app, event)
            app.NumberofDetector = str2double(app.NumberofDetectorDropDown.Value);
            %disp(['Selected Num of Detector: ' num2str(app.NumberofDetector)]);
        end

        % Value changed function: DetectorDensityDropDown
        function DetectorDensityDropDownValueChanged(app, event)
            app.selectedDensity = str2double(app.DetectorDensityDropDown.Value);
            %disp(['Selected Detector Dest: ' num2str(app.selectedDensity)]);
        end

        % Value changed function: SourceDistanceDropDown
        function SourceDistanceDropDownValueChanged(app, event)
            app.sourceDest = str2double(app.SourceDistanceDropDown.Value);
            %disp(['Selected Source Dist: ' num2str(app.sourceDest)]);
        end

        % Value changed function: RotationStepAngleSlider
        function RotationStepAngleSliderValueChanged(app, event)
            app.rotationSlider = app.RotationStepAngleSlider.Value;
            %disp(['Selected Rotation Step Angle: ' num2str(app.rotationSlider)]);
        end

        % Value changed function: DetectorArrayTypeDropDown
        function DetectorArrayTypeDropDownValueChanged(app, event)
            app.arrTypeDropDown = app.DetectorArrayTypeDropDown.Value;
            % Use the new value to update your program
            app.detector_array_type = app.arrTypeDropDown;
            %disp(['Selected Arr Type: ' (app.arrTypeDropDown)]);
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            if value == 1 % If the Run button is pressed
                % Retrieve and validate the parameters
                app.NumberofDetector = str2double(app.NumberofDetectorDropDown.Value);
                app.selectedDensity = str2double(app.DetectorDensityDropDown.Value);
                app.sourceDest = str2double(app.SourceDistanceDropDown.Value);
                app.rotationSlider = app.RotationStepAngleSlider.Value; % Already a numeric value
                app.arrTypeDropDown = app.DetectorArrayTypeDropDown.Value; % String value
        
                % Check for valid number for Number of Detector, Source Distance, and Detector Density
                if isnan(app.NumberofDetector) || isnan(app.selectedDensity) || isnan(app.sourceDest)
                    disp('Invalid input for number of detectors, source distance, or detector density. Please select valid numbers.');
                    return;
                end
        
                % Display the selected parameters
                disp(['Selected Num of Detector: ' num2str(app.NumberofDetector)]);
                disp(['Selected Detector Density: ' num2str(app.selectedDensity)]);
                disp(['Selected Source Distance: ' num2str(app.sourceDest)]);
                disp(['Selected Rotation Step Angle: ' num2str(app.rotationSlider)]);
                disp(['Selected Detector Array Type: ' app.arrTypeDropDown]);
        
                % Generate the phantom
                GenPhantom  = app.generate_phantom(); % Assuming you have a method to generate the phantom
        
                % Operate the scanner
                % This will use the retrieved values for scanning
                scanner_data = app.operate_scanner(GenPhantom , app.NumberofDetector, app.selectedDensity, app.arrTypeDropDown, app.sourceDest, app.rotationSlider);
        
                % Reconstruct the image
                reconstructed_image = app.reconstruct_image(scanner_data);
        
                % Display the reconstructed image
                imshow(reconstructed_image, 'Parent', app.UIAxes2);
            end
        end

        % Selection changed function: TypeofImageAnalysisButtonGroup
        function TypeofImageAnalysisButtonGroupSelectionChanged(app, event)
            selectedAnalysis = app.TypeofImageAnalysisButtonGroup.SelectedObject.Text;  % Corrected this line

            switch selectedAnalysis
                case 'SI and Contrast'
                    calculateContrast(app);
                case 'Image Difference'
                    imageDifferenceAnalysis(app);
                case 'SI Profiles'
                    signalIntensityProfile(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PhantomGenerationTab = uitab(app.TabGroup);
            app.PhantomGenerationTab.Title = 'Phantom Generation';
            app.UIAxes = uiaxes(app.PhantomGenerationTab);
            title(app.UIAxes, 'Phantom')
            app.MatrixSizeEditFieldLabel = uilabel(app.PhantomGenerationTab);
            app.MatrixSizeEditFieldLabel.Text = 'Matrix Size';
            app.PhantomStructureButtonGroup = uibuttongroup(app.PhantomGenerationTab);
            app.PhantomStructureButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhantomStructureButtonGroupSelectionChanged, true);
            app.PhantomStructureButtonGroup.Title = 'Phantom Structure';
            app.CircularButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.RectangularButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.HeadPhantomButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.HeadPhantomButton.Text = 'Head Phantom';
            app.MatrixSizeEditField = uieditfield(app.PhantomGenerationTab, 'numeric');
            app.ShowphatomButton = uibutton(app.PhantomGenerationTab, 'push');
            app.ShowphatomButton.ButtonPushedFcn = createCallbackFcn(app, @ShowphatomButtonPushed, true);
            app.ShowphatomButton.Text = 'Show phatom';
            app.StructureSizeEditFieldLabel = uilabel(app.PhantomGenerationTab);
            app.StructureSizeEditFieldLabel.Text = 'Structure Size';
            app.StructureSizeEditField = uieditfield(app.PhantomGenerationTab, 'numeric');
            app.StructureSizeEditField.Value = 1;
            app.StructureColorEditFieldLabel = uilabel(app.PhantomGenerationTab);
            app.StructureColorEditFieldLabel.Text = 'Structure Color';
            app.StructureColorEditField = uieditfield(app.PhantomGenerationTab, 'numeric');
            app.StructureColorEditField.Value = 0.7;
            app.ScannerControlsTab = uitab(app.TabGroup);
            app.ScannerControlsTab.Title = 'Scanner Controls';
            app.UIAxes2 = uiaxes(app.ScannerControlsTab);
            title(app.UIAxes2, ' Reconstructed Image')
            app.NumberofDetectorDropDownLabel = uilabel(app.ScannerControlsTab);
            app.NumberofDetectorDropDownLabel.Text = 'Number of Detector';
            app.NumberofDetectorDropDown = uidropdown(app.ScannerControlsTab);
            app.NumberofDetectorDropDown.Items = {'50', '100', '200', '300', '400', '500', '750', '1000', '1250', '1500', '2000'};
            app.NumberofDetectorDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofDetectorDropDownValueChanged, true);
            app.NumberofDetectorDropDown.Value = '100';
            app.DetectorDensityDropDownLabel = uilabel(app.ScannerControlsTab);
            app.DetectorDensityDropDownLabel.Text = 'Detector Density';
            app.DetectorDensityDropDown = uidropdown(app.ScannerControlsTab);
            app.DetectorDensityDropDown.Items = {'0', '0.5', '1.0', '1.5', '2.0', '2.5', '3.0', '3.5', '4.0'};
            app.DetectorDensityDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorDensityDropDownValueChanged, true);
            app.DetectorDensityDropDown.Value = '0';
            app.SourceDistanceDropDownLabel = uilabel(app.ScannerControlsTab);
            app.SourceDistanceDropDownLabel.Text = 'Source Distance';
            app.DetectorArrayTypeDropDown = uidropdown(app.ScannerControlsTab);
            app.DetectorArrayTypeDropDown.Items = {'Linear', 'Arc'};
            app.DetectorArrayTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorArrayTypeDropDownValueChanged, true);
            app.DetectorArrayTypeDropDown.Value = 'Linear';
            app.DetectorArrayTypeDropDownLabel = uilabel(app.ScannerControlsTab);
            app.DetectorArrayTypeDropDownLabel.Text = 'Detector Array Type';
            app.SourceDistanceDropDown = uidropdown(app.ScannerControlsTab);
            app.SourceDistanceDropDown.Items = {'100', '150', '200', '250', '300', '400', '500'};
            app.SourceDistanceDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceDistanceDropDownValueChanged, true);
            app.SourceDistanceDropDown.Value = '100';
            app.RunButton = uibutton(app.ScannerControlsTab, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.RotationStepAngleSliderLabel = uilabel(app.ScannerControlsTab);
            app.RotationStepAngleSliderLabel.Text = 'Rotation Step Angle';
            app.RotationStepAngleSlider = uislider(app.ScannerControlsTab);
            app.RotationStepAngleSlider.Limits = [1 10];
            app.RotationStepAngleSlider.ValueChangedFcn = createCallbackFcn(app, @RotationStepAngleSliderValueChanged, true);
            app.RotationStepAngleSlider.Value = 1;
            app.ImageAnalysisTab = uitab(app.TabGroup);
            app.ImageAnalysisTab.Title = 'Image Analysis';
            app.UIAxes3 = uiaxes(app.ImageAnalysisTab);
            title(app.UIAxes3, 'Image Analysis')
            app.TypeofImageAnalysisButtonGroup = uibuttongroup(app.ImageAnalysisTab);
            app.TypeofImageAnalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofImageAnalysisButtonGroupSelectionChanged, true);
            app.TypeofImageAnalysisButtonGroup.Title = 'Type of Image Analysis';
            app.SIandContrastButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.SIandContrastButton.Text = 'SI and Contrast';
            app.SIandContrastButton.Value = true;
            app.ImageDifferenceButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.ImageDifferenceButton.Text = 'Image Difference';
            app.SIProfilesButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.SIProfilesButton.Text = 'SI Profiles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CTScanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end